classdef Calibrazione_EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RESETButton                  matlab.ui.control.Button
        Threshold2lowTextArea        matlab.ui.control.TextArea
        Threshold2lowTextAreaLabel   matlab.ui.control.Label
        Threshold2highTextArea       matlab.ui.control.TextArea
        Threshold2highTextAreaLabel  matlab.ui.control.Label
        Threshold1lowTextArea        matlab.ui.control.TextArea
        Threshold1lowTextAreaLabel   matlab.ui.control.Label
        Threshold1highTextArea       matlab.ui.control.TextArea
        Threshold1highTextAreaLabel  matlab.ui.control.Label
        STARTButton                  matlab.ui.control.Button
        SimulationButtonGroup        matlab.ui.container.ButtonGroup
        CloseButton                  matlab.ui.control.RadioButton
        OpenButton                   matlab.ui.control.RadioButton
        RestButton                   matlab.ui.control.RadioButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            modello = 'Sim_app';
            % load the model if not already open
            if ~bdIsLoaded(modello)
                load_system(modello);
            end
            
            % Esegui la simulazione e salva i risultati
            results = sim(modello);
            muxOutput = results.get('out'); % Ottieni il dataset

            selected_option = app.SimulationButtonGroup.SelectedObject.Text;
            switch selected_option
                case 'Rest'
                    %app.Label.Text = 'Stay sill for 20 seconds';
                    e1_low = muxOutput.getElement('e1').Values.Data;
                    e2_low = muxOutput.getElement('e2').Values.Data;
                    e1_low_per = prctile(e1_low, 50);
                    e2_low_per = prctile(e2_low, 50);
                    app.Threshold1lowTextArea.Value = num2str(e1_low_per);
                    app.Threshold2lowTextArea.Value = num2str(e2_low_per);

                case 'Open'
                    %app.Label.Text = 'Open your hand repeatedly for 20 seconds';
                    e2_high = muxOutput.getElement('e2').Values.Data;
                    e2_high_per = prctile(e2_high, 95);
                    app.Threshold2highTextArea.Value = num2str(e2_high_per);

                case 'Close'
                    %app.Label.Text = 'Close your hand repeatedly for 20 seconds';
                    e1_high = muxOutput.getElement('e1').Values.Data;
                    e1_high_per = prctile(e1_high, 95);
                    app.Threshold1highTextArea.Value = num2str(e1_high_per);
            end


        end

        % Callback function
        function COMPUTEButtonPushed(app, event)


        end

        % Selection changed function: SimulationButtonGroup
        function SimulationButtonGroupSelectionChanged(app, event)


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.Threshold1lowTextArea.Value = ' ';
            app.Threshold2lowTextArea.Value = ' ';
            app.Threshold2highTextArea.Value = ' ';
            app.Threshold1highTextArea.Value = ' ';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationButtonGroup = uibuttongroup(app.UIFigure);
            app.SimulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationButtonGroupSelectionChanged, true);
            app.SimulationButtonGroup.Title = 'Simulation';
            app.RestButton = uiradiobutton(app.SimulationButtonGroup);
            app.RestButton.Text = 'Rest';
            app.RestButton.Value = true;
            app.OpenButton = uiradiobutton(app.SimulationButtonGroup);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uiradiobutton(app.SimulationButtonGroup);
            app.CloseButton.Text = 'Close';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Threshold1highTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold1highTextAreaLabel.Text = 'Threshold 1 high';
            app.Threshold1highTextArea = uitextarea(app.UIFigure);
            app.Threshold1lowTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold1lowTextAreaLabel.Text = 'Threshold 1 low';
            app.Threshold1lowTextArea = uitextarea(app.UIFigure);
            app.Threshold2highTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold2highTextAreaLabel.Text = 'Threshold 2 high';
            app.Threshold2highTextArea = uitextarea(app.UIFigure);
            app.Threshold2lowTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold2lowTextAreaLabel.Text = 'Threshold 2 low';
            app.Threshold2lowTextArea = uitextarea(app.UIFigure);
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calibrazione_EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end